classdef Task_4_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SIMULATE            matlab.ui.control.Button
        SAVE                matlab.ui.control.Button
        LOAD                matlab.ui.control.Button
        startSP             matlab.ui.control.NumericEditField
        SPEditFieldLabel    matlab.ui.control.Label
        stepSP              matlab.ui.control.NumericEditField
        EditField_3Label    matlab.ui.control.Label
        stopSP              matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        tinsp               matlab.ui.control.NumericEditField
        tinspLabel          matlab.ui.control.Label
        N                   matlab.ui.control.NumericEditField
        NEditFieldLabel     matlab.ui.control.Label
        Q                   matlab.ui.control.NumericEditField
        QEditFieldLabel     matlab.ui.control.Label
        R                   matlab.ui.control.NumericEditField
        REditFieldLabel     matlab.ui.control.Label
        TOP                 matlab.ui.control.NumericEditField
        TOPEditFieldLabel   matlab.ui.control.Label
        CL                  matlab.ui.control.NumericEditField
        CLEditFieldLabel    matlab.ui.control.Label
        K                   matlab.ui.control.NumericEditField
        KEditFieldLabel     matlab.ui.control.Label
        Cfrc                matlab.ui.control.NumericEditField
        CfrcEditFieldLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOAD
        function LOADButtonPushed(app, event)
            
            [fileName,pathName] = uigetfile('.mat','Select Patient file');
            filePath = fullfile(pathName,fileName);
            app.data =  load(filePath);
           
           cl = app.data.parameters.CL;
           CFRC = app.data.parameters.Cfrc;
           top = app.data.parameters.TOP;
           k = app.data.parameters.K;
           r = app.data.parameters.R;
           q = app.data.parameters.Q;
           n = app.data.parameters.N;
           time = app.data.parameters.tinsp;
           SP = app.data.parameters.SP;
           
           app.CL.Value = cl;
           app.Cfrc.Value = CFRC;
           app.TOP.Value = top;
           app.K.Value = k;
           app.R.Value = r;
           app.Q.Value = q;
           app.N.Value = n;
           app.tinsp.Value = time;
           app.startSP.Value = SP(1);
           app.stepSP.Value = SP(2)-SP(1);
           app.stopSP.Value = SP(end);
           
           app.SIMULATE.Enable = 'on';
           app.SAVE.Enable = 'on';
        end

        % Button pushed function: SAVE
        function SAVEButtonPushed(app, event)
                cl = app.CL.Value;
                CFRC = app.Cfrc.Value;
                top =  app.TOP.Value;
                k =  app.K.Value;
                r =  app.R.Value;
                q =   app.Q.Value;
                n =  app.N.Value;
                time =  app.tinsp.Value;
                sp = app.startSP.Value:app.stepSP.Value:app.stopSP.Value;
                
                [fileName,pathName] = uiputfile('.mat','Set filename');
                filePath = fullfile(pathName,fileName);
                if (length(filePath)>3)
            
                parameters = struct('CL',cl,'Cfrc',CFRC,'TOP',top,'K',k,'R',r,'Q',q,'N',n,'tinsp',time,'SP',sp);
                save(filePath,'parameters');
                end
        end

        % Button pushed function: SIMULATE
        function SIMULATEButtonPushed(app, event)
                cl = app.CL.Value;
                CFRC = app.Cfrc.Value;
                top =  app.TOP.Value;
                k =  app.K.Value;
                r =  app.R.Value;
                q =   app.Q.Value;
                n =  app.N.Value;
                time =  0:0.05:app.tinsp.Value;
                sp = app.startSP.Value:app.stepSP.Value:app.stopSP.Value;
                PEEP = 0;                                                              
              [time,Palv] = ode45(@(tinsp,Palv) task2run(tinsp,Palv,CFRC,k,cl,top,sp,n),time,PEEP);

            Paw  = r*q + Palv;
            plot(app.UIAxes,time ,Paw,'k');
            title(app.UIAxes, 'Airway pressure')
            xlabel(app.UIAxes, 'pressure[mbar]')
            ylabel(app.UIAxes, 'time[s]')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CfrcEditFieldLabel = uilabel(app.UIFigure);
            app.CfrcEditFieldLabel.Text = 'Cfrc';
            app.Cfrc = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.K = uieditfield(app.UIFigure, 'numeric');
            app.CLEditFieldLabel = uilabel(app.UIFigure);
            app.CLEditFieldLabel.Text = 'CL';
            app.CL = uieditfield(app.UIFigure, 'numeric');
            app.TOPEditFieldLabel = uilabel(app.UIFigure);
            app.TOPEditFieldLabel.Text = 'TOP';
            app.TOP = uieditfield(app.UIFigure, 'numeric');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.R = uieditfield(app.UIFigure, 'numeric');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.Q = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.N = uieditfield(app.UIFigure, 'numeric');
            app.tinspLabel = uilabel(app.UIFigure);
            app.tinspLabel.Text = 'tinsp';
            app.tinsp = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = ':';
            app.stopSP = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = ':';
            app.stepSP = uieditfield(app.UIFigure, 'numeric');
            app.SPEditFieldLabel = uilabel(app.UIFigure);
            app.SPEditFieldLabel.Text = 'SP';
            app.startSP = uieditfield(app.UIFigure, 'numeric');
            app.LOAD = uibutton(app.UIFigure, 'push');
            app.LOAD.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOAD.Text = 'LOAD';
            app.SAVE = uibutton(app.UIFigure, 'push');
            app.SAVE.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVE.Text = 'SAVE';
            app.SIMULATE = uibutton(app.UIFigure, 'push');
            app.SIMULATE.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButtonPushed, true);
            app.SIMULATE.Text = 'SIMULATE ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task_4_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end